classdef DeltaZ_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeltaZGUIUIFigure            matlab.ui.Figure
        Label                        matlab.ui.control.Label
        HomeButton                   matlab.ui.control.Button
        DeltaZLabel                  matlab.ui.control.Label
        CurrentPositionPanel         matlab.ui.container.Panel
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        TeleoperatePositionPanel     matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        ZPositionSlider              matlab.ui.control.Slider
        ZPositionLabel               matlab.ui.control.Label
        MoveToPositionPanel          matlab.ui.container.Panel
        XEditField_2Label            matlab.ui.control.Label
        XEditFieldMoveTo             matlab.ui.control.NumericEditField
        YEditField_2Label            matlab.ui.control.Label
        YEditFieldMoveTo             matlab.ui.control.NumericEditField
        ZEditField_2Label            matlab.ui.control.Label
        ZEditFieldMoveTo             matlab.ui.control.NumericEditField
        MoveToGoButton               matlab.ui.control.Button
        EditField_4Label             matlab.ui.control.Label
        t1EditFieldMoveTo            matlab.ui.control.NumericEditField
        EditField_5Label             matlab.ui.control.Label
        t2EditFieldMoveTo            matlab.ui.control.NumericEditField
        EditField_6Label             matlab.ui.control.Label
        t3EditFieldMoveTo            matlab.ui.control.NumericEditField
        AngleMoveToGoButton          matlab.ui.control.Button
        CurrentJointAnglesPanel      matlab.ui.container.Panel
        EditFieldLabel               matlab.ui.control.Label
        t1EditField                  matlab.ui.control.NumericEditField
        EditField_2Label             matlab.ui.control.Label
        t2EditField                  matlab.ui.control.NumericEditField
        EditField_3Label             matlab.ui.control.Label
        t3EditField                  matlab.ui.control.NumericEditField
        SpecialFunctionsPanel        matlab.ui.container.Panel
        SF2Button                    matlab.ui.control.Button
        SF1Button                    matlab.ui.control.Button
        SF3Button                    matlab.ui.control.Button
        SF4Button                    matlab.ui.control.Button
        TeleoperateAnglePanel        matlab.ui.container.Panel
        SliderLabel                  matlab.ui.control.Label
        t1Slider                     matlab.ui.control.Slider
        Label_5                      matlab.ui.control.Label
        t2Slider                     matlab.ui.control.Slider
        Label_6                      matlab.ui.control.Label
        t3Slider                     matlab.ui.control.Slider
        TeleoperateAngleButtonGroup  matlab.ui.container.ButtonGroup
        EnableButton_2               matlab.ui.control.RadioButton
        DisableButton_2              matlab.ui.control.RadioButton
        SavePositionPanel            matlab.ui.container.Panel
        Pos1GoButton                 matlab.ui.control.Button
        Pos1SetButton                matlab.ui.control.Button
        Pos2GoButton                 matlab.ui.control.Button
        Pos2SetButton                matlab.ui.control.Button
        Pos3GoButton                 matlab.ui.control.Button
        Pos3SetButton                matlab.ui.control.Button
        Pos1Label                    matlab.ui.control.Label
        Pos2Label                    matlab.ui.control.Label
        Pos3Label                    matlab.ui.control.Label
        Pos1ResetButton              matlab.ui.control.Button
        Pos2ResetButton              matlab.ui.control.Button
        Pos3ResetButton              matlab.ui.control.Button
        Pos4GoButton                 matlab.ui.control.Button
        Pos4SetButton                matlab.ui.control.Button
        Pos4Label                    matlab.ui.control.Label
        Pos4ResetButton              matlab.ui.control.Button
        Pos1ValsLabel                matlab.ui.control.Label
        Pos2ValsLabel                matlab.ui.control.Label
        Pos4ValsLabel                matlab.ui.control.Label
        Pos3ValsLabel                matlab.ui.control.Label
        Pos1ResetButton_2            matlab.ui.control.Button
        StickPanel                   matlab.ui.container.Panel
        StickUIAxes                  matlab.ui.control.UIAxes
        StickViewButtonGroup         matlab.ui.container.ButtonGroup
        EnableButton                 matlab.ui.control.RadioButton
        DisableButton                matlab.ui.control.RadioButton
        ConfigureButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        SelectedIndex (1,1) = nan;
        Point;
        ZPos = -47.06;
        PointMoving (1,1) logical = false;
        Workspace;
        t1 = 0;
        t2 = 0;
        t3 = 0;
        RMax = 30;
        DialogApp;
        savedPos1 = [0,0,-47];
        savedPos2 = [0,0,-47];
        savedPos3 = [0,0,-47];
        savedPos4 = [0,0,-47];
        xHome = 0;
        yHome = 0;
        zHome = -47.06;
        baseTriangle;
        motors;
        R1;
        R2;
        R3;
        arm1;
        arm2;
        arm3;
        ee;
        base;
        stickFlag = 0;
        f = 50;
        e = 25;
        rf = 30.0;
    end
    
    properties (Access = public)
        R = 30;
        serialObj;
        ZMin = -75;
        ZMax = -35;
        Port = '';
    end
    
    methods (Access = public)
        function serialObjCallback(app, serialObj, eventData)
            message = strtrim(readline(app.serialObj));
            
            % No Parameters
            if strcmpi(message,"B")
                app.counter = app.counter+1;
                app.updateView();
            end
            
            % Parameters
            messageArray = split(message);
            
            
            if strcmpi(messageArray(1),"ATANG")
                app.t1 = str2double(messageArray(2));
                app.t2 = str2double(messageArray(3));
                app.t3 = str2double(messageArray(4));
                app.t1EditField.Value = app.t1;
                app.t2EditField.Value = app.t2;
                app.t3EditField.Value = app.t3;
                app.updateView();
            end
            
            if strcmpi(messageArray(1),"ATPOS")
                app.Point.XData(1) = str2double(messageArray(2));
                app.Point.YData(1) = str2double(messageArray(3));
                app.ZPos = str2double(messageArray(4));
                app.XEditField.Value = app.Point.XData(1);
                app.YEditField.Value = app.Point.YData(1) ;
                app.ZEditField.Value = app.ZPos;
                app.updateView();
            end
            
        end
        
        function sendGoTo(app, x, y, z)
            % degrees is a number (maybe an int, maybe a double)
            x = round(x,2);
            y = round(y,2);
            z = round(z,2);
            command = sprintf("GOTO %.2f,%.2f,%.2f", x,y,z);
            %             fprintf("Sending: %s\n", command);
            writeline(app.serialObj,command);
        end
        
        function sendGoToAngle(app, angle1, angle2, angle3)
            % degrees is a number (maybe an int, maybe a double)
            angle1 = round(angle1);
            angle2 = round(angle2);
            angle3 = round(angle3);
            command = sprintf("ANG %d,%d,%d", angle1,angle2,angle3);
            fprintf("Sending: %s\n", command)
            writeline(app.serialObj,command);
        end
        
        function sendSF(app, commandNumber)
            command = sprintf("SF %d", commandNumber);
            fprintf("Sending: %s\n", command)
            writeline(app.serialObj,command);
        end
        
        function setPoint(app, event)
            if isnan(app.SelectedIndex)
                [~,app.SelectedIndex] = min(...
                    (event.Source.XData-event.IntersectionPoint(1)).^2+...
                    (event.Source.YData-event.IntersectionPoint(2)).^2);
            end
        end
        function resetPoint(app, event)
            if isnan(app.SelectedIndex)
                [~,app.SelectedIndex] = min(...
                    (event.Source.XData-event.IntersectionPoint(1)).^2+...
                    (event.Source.YData-event.IntersectionPoint(2)).^2);
            end
        end
        
        function updateView(app)
            app.XEditField.Value = round(app.Point.XData(1),2);
            app.YEditField.Value = round(app.Point.YData(1),2);
            app.ZEditField.Value = round(app.ZPos,2);
            app.ZPositionSlider.Value = app.ZPos;
            if strcmp(app.TeleoperateAngleButtonGroup.SelectedObject.Text,"Enable")
                app.t1Slider.Value = app.t1;
                app.t2Slider.Value = app.t2;
                app.t3Slider.Value = app.t3;
            end
            %             fprintf("%.3\n",(sqrt(app.Point.XData(1)^2+app.Point.YData(1)^2)))
            if round(sqrt(app.Point.XData(1)^2+app.Point.YData(1)^2))>=30
                app.Point.Color = 'red';
            else
                app.Point.Color = 'blue';
            end
            app.t1EditField.Value = round(app.t1);
            app.t2EditField.Value = round(app.t2);
            app.t3EditField.Value = round(app.t3);
            if strcmp(app.StickViewButtonGroup.SelectedObject.Text,"Enable")
                app.stickPlot();
            end
        end
        
        function goHome(app)
            writeline(app.serialObj,"HOME");
            app.t1 = 0;
            app.t2 = 0;
            app.t3 = 0;
            app.updateView();
        end
        
        function stickPlot(app)
            if app.stickFlag == 0
                phi1 = 270*pi/180;
                phi2 = 150*pi/180;
                phi3 = pi/6;

                app.motors = [0              -app.f*cos(pi/6)*sqrt(3)/6   app.f*cos(pi/6)*sqrt(3)/6;
                    -app.f*sqrt(3)/6    app.f*sin(pi/6)*sqrt(3)/6   app.f*sin(pi/6)*sqrt(3)/6;
                    0               0                       0];
                app.R1 = [cos(phi1) -sin(phi1) 0;
                    sin(phi1)  cos(phi1) 0;
                    0             0       1];
                app.R2 = [cos(phi2) -sin(phi2) 0;
                    sin(phi2)  cos(phi2) 0;
                    0             0       1];
                
                app.R3 = [cos(phi3) -sin(phi3) 0;
                    sin(phi3)  cos(phi3) 0;
                    0             0       1];
                
                %plot base 
                cla(app.StickUIAxes,'reset')
                rBase = app.f/(2*sqrt(3));
                th = 0:pi/50:2*pi;
                x_circle = rBase * cos(th);
                y_circle = rBase * sin(th);
                app.base = fill(app.StickUIAxes,x_circle, y_circle, 'blue')
                
%                                 app.baseTriangle = [0            app.f/2                -app.f/2;
%                     app.f/sqrt(3)    -app.f/(2*sqrt(3))     -app.f/(2*sqrt(3));
%                     0            0                  0];
%                 xBaseTrangle = [app.baseTriangle(1,1:3).'];
%                 yBaseTrangle = [app.baseTriangle(2,1:3).'];
%                 app.base = fill(app.StickUIAxes,xBaseTrangle,yBaseTrangle,'blue');
                axis(app.StickUIAxes,[-50 50 -50 50 -80 25])
                axis(app.StickUIAxes,'square')
                xlabel(app.StickUIAxes,'x')
                ylabel(app.StickUIAxes,'y')
                zlabel(app.StickUIAxes,'z')
                grid(app.StickUIAxes,'on')
                view(app.StickUIAxes,-25,25)
                app.stickFlag = 1;
                x0 = app.Point.XData(1);
                y0 = app.Point.YData(1);
                z0 = app.ZPos;
                [ang1,ang2,ang3] = deltaIK(x0,y0,z0);
                ang1 = ang1*pi/180;
                ang2 = ang2*pi/180;
                ang3 = ang3*pi/180;
                elbows = [app.motors(1:3,1)+app.rf*app.R1*[cos(ang1);0;-sin(ang1)],app.motors(1:3,2)+app.rf*app.R2*[cos(ang2);0;-sin(ang2)],app.motors(1:3,3)+app.rf*app.R3*[cos(ang3);0;-sin(ang3)]];
                eeTranslation = [x0 x0 x0;
                    y0 y0 y0;
                    z0 z0 z0];
                eeTriangle =   [0            app.e/2                -app.e/2;
                    app.e/sqrt(3)    -app.e/(2*sqrt(3))     -app.e/(2*sqrt(3));
                    0            0                  0]+eeTranslation;
                eeConnections = [0              -app.e*cos(pi/6)*sqrt(3)/6   app.e*cos(pi/6)*sqrt(3)/6;
                    -app.e*sqrt(3)/6    app.e*sin(pi/6)*sqrt(3)/6   app.e*sin(pi/6)*sqrt(3)/6;
                    0              0                      0]+eeTranslation;
                xArm1 = [app.motors(1,1);elbows(1,1);eeConnections(1,1)];
                yArm1 = [app.motors(2,1);elbows(2,1);eeConnections(2,1)];
                zArm1 = [app.motors(3,1);elbows(3,1);eeConnections(3,1)];
                xArm2 = [app.motors(1,2);elbows(1,2);eeConnections(1,2)];
                yArm2 = [app.motors(2,2);elbows(2,2);eeConnections(2,2)];
                zArm2 = [app.motors(3,2);elbows(3,2);eeConnections(3,2)];
                xArm3 = [app.motors(1,3);elbows(1,3);eeConnections(1,3)];
                yArm3 = [app.motors(2,3);elbows(2,3);eeConnections(2,3)];
                zArm3 = [app.motors(3,3);elbows(3,3);eeConnections(3,3)];
                
                hold(app.StickUIAxes,'on')
                app.arm1 = plot3(app.StickUIAxes,xArm1,yArm1,zArm1,'black','LineWidth',3);
                get(app.arm1)
                hold(app.StickUIAxes,'on')
                app.arm2 = plot3(app.StickUIAxes,xArm2,yArm2,zArm2,'cyan','LineWidth',3);
                hold(app.StickUIAxes,'on')
                app.arm3 = plot3(app.StickUIAxes,xArm3,yArm3,zArm3,'magenta','LineWidth',3);
                
                %blot ee triangle
                hold(app.StickUIAxes,'on')
                xeeTrangle = [eeTriangle(1,1:3).';eeTriangle(1,1)];
                yeeTrangle = [eeTriangle(2,1:3).';eeTriangle(2,1)];
                zeeTriangle = [eeTriangle(3,1:3).';eeTriangle(3,1)];
                app.ee = plot3(app.StickUIAxes,xeeTrangle,yeeTrangle,zeeTriangle,'red','LineWidth',3);
                legend(app.StickUIAxes,'base','arm1','arm2','arm3','ee',"Location","east")
            else
                x0 = app.Point.XData(1);
                y0 = app.Point.YData(1);
                z0 = app.ZPos;
                [ang1,ang2,ang3] = deltaIK(x0,y0,z0);
                ang1 = ang1*pi/180;
                ang2 = ang2*pi/180;
                ang3 = ang3*pi/180;
                elbows = [app.motors(1:3,1)+app.rf*app.R1*[cos(ang1);0;-sin(ang1)],app.motors(1:3,2)+app.rf*app.R2*[cos(ang2);0;-sin(ang2)],app.motors(1:3,3)+app.rf*app.R3*[cos(ang3);0;-sin(ang3)]];
                eeTranslation = [x0 x0 x0;
                    y0 y0 y0;
                    z0 z0 z0];
                eeTriangle =   [0            app.e/2                -app.e/2;
                    app.e/sqrt(3)    -app.e/(2*sqrt(3))     -app.e/(2*sqrt(3));
                    0            0                  0]+eeTranslation;
                eeConnections = [0              -app.e*cos(pi/6)*sqrt(3)/6   app.e*cos(pi/6)*sqrt(3)/6;
                    -app.e*sqrt(3)/6    app.e*sin(pi/6)*sqrt(3)/6   app.e*sin(pi/6)*sqrt(3)/6;
                    0              0                      0]+eeTranslation;
                
                app.arm1.XData = [app.motors(1,1);elbows(1,1);eeConnections(1,1)];
                app.arm1.YData  = [app.motors(2,1);elbows(2,1);eeConnections(2,1)];
                app.arm1.ZData  = [app.motors(3,1);elbows(3,1);eeConnections(3,1)];
                app.arm2.XData = [app.motors(1,2);elbows(1,2);eeConnections(1,2)];
                app.arm2.YData = [app.motors(2,2);elbows(2,2);eeConnections(2,2)];
                app.arm2.ZData = [app.motors(3,2);elbows(3,2);eeConnections(3,2)];
                app.arm3.XData = [app.motors(1,3);elbows(1,3);eeConnections(1,3)];
                app.arm3.YData = [app.motors(2,3);elbows(2,3);eeConnections(2,3)];
                app.arm3.ZData = [app.motors(3,3);elbows(3,3);eeConnections(3,3)];
                app.ee.XData = [eeTriangle(1,1:3).';eeTriangle(1,1)];
                app.ee.YData = [eeTriangle(2,1:3).';eeTriangle(2,1)];
                app.ee.ZData = [eeTriangle(3,1:3).';eeTriangle(3,1)];
            end
            
            
        end
        
        function updateXYPlot(app)
            cla(app.UIAxes,'reset')
            app.Point = plot(app.UIAxes,[0],[0],'*','LineWidth',10);
            %             axis(app.UIAxes,[-app.R-2 app.R+2 -app.R-2 app.R+2])
            app.Point.ButtonDownFcn = createCallbackFcn(app, @setPoint, true);
            th = 0:pi/50:2*pi;
            xunit = app.R * cos(th);
            yunit = app.R * sin(th);
            hold(app.UIAxes, 'on' )
            app.Workspace = plot(app.UIAxes,xunit, yunit,'k-');
            grid(app.UIAxes,'on')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.updateXYPlot();
            
            prompt = {'Enter Arduino COM Port:'};
            dlgtitle = 'Select COM Port';
            dims = [1 35];
            definput = {'COM5'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.Port = answer{1};
            flag = 0;
            while flag == 0
                try
                    app.serialObj = serialport(app.Port,9600);
                    flag = 1;
                catch
                    flag = 0;
                    beep
                    prompt = {'Enter Arduino COM Port:'};
                    dlgtitle = 'Select COM Port';
                    dims = [1 35];
                    definput = {'COM5','hsv'};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    app.Port = answer{1};
                    
                end
            end


            %             app.serialObj = serialport(app.Port,9600);
            flush(app.serialObj);
            configureCallback(app.serialObj, "terminator", @app.serialObjCallback);
            
            app.updateView();
        end

        % Window button motion function: DeltaZGUIUIFigure
        function DeltaZGUIUIFigureWindowButtonDown(app, event)
            if ~isnan(app.SelectedIndex)
                app.Point.Color = 'blue';
                p = event.Source.CurrentPoint;
                x = p(1);
                y = p(2);
                x1 = (app.UIAxes.InnerPosition(1));
                x2 = (sum(app.UIAxes.InnerPosition([1 3])));
                y1 = (app.UIAxes.InnerPosition(2));
                y2 = (sum(app.UIAxes.InnerPosition([2 4])));
                rPixel = ((y2-y1)/2);
                xCenter = (x1 + x2)/2;
                yCenter = (y1 + y2)/2;
                rCurrent = sqrt((x-xCenter)^2+(y-yCenter)^2);
                if rCurrent<rPixel-2
                    xl = app.UIAxes.XLim;
                    yl = app.UIAxes.YLim;
                    xax = xl(1)+(xl(2)-xl(1))*(x-x1)/(x2-x1);
                    yax = yl(1)+(yl(2)-yl(1))*(y-y1)/(y2-y1);
                    app.Point.XData(app.SelectedIndex) = xax;
                    app.Point.YData(app.SelectedIndex) = yax;
                    xlim(app.UIAxes,xl);
                    ylim(app.UIAxes,yl);
                    
                    app.sendGoTo(xax,yax,app.ZPos)
                    
                else
                    app.Label.Text = 'Workspace Limit';
                    app.Point.Color = 'red';
                    fprintf('Out\n')
                end
                app.updateView();
            end
            
        end

        % Window button up function: DeltaZGUIUIFigure
        function DeltaZGUIUIFigureWindowButtonUp(app, event)
            if ~isnan(app.SelectedIndex)
                app.SelectedIndex = nan;
            end
        end

        % Value changing function: ZPositionSlider
        function ZPositionSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ZPos = changingValue;
            app.updateView()
            app.sendGoTo(app.Point.XData(1),app.Point.YData(1),app.ZPos)
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.Point.XData(1) = 0;
            app.Point.YData(1) = 0;
            app.ZPos = app.zHome;
            app.t1 = 0;
            app.t2 = 0;
            app.t3 = 0;
            app.goHome();
            app.TeleoperateAnglePanel.BackgroundColor = [0.94,0.94,0.94];
            app.updateView()
        end

        % Value changing function: t1Slider
        function t1SliderValueChanging(app, event)
            changingValue = event.Value;
            if forwardKinTest(changingValue,app.t2,app.t3, app.R, app.ZMin, app.ZMax)
                app.TeleoperateAnglePanel.BackgroundColor = [0.94,0.94,0.94];
                app.t1Slider.Enable = true;
                app.t1 = changingValue;
                app.sendGoToAngle(app.t1,app.t2,app.t3);
                app.updateView();
            else
                app.TeleoperateAnglePanel.BackgroundColor = [1,0,0];
            end
        end

        % Value changing function: t2Slider
        function t2SliderValueChanging(app, event)
            changingValue = event.Value;
            if forwardKinTest(app.t1,changingValue,app.t3, app.R, app.ZMin, app.ZMax)
                app.TeleoperateAnglePanel.BackgroundColor = [0.94,0.94,0.94];
                app.t2 = changingValue;
                app.sendGoToAngle(app.t1,app.t2,app.t3);
                app.updateView();
            else
                app.TeleoperateAnglePanel.BackgroundColor = [1,0,0];
            end
        end

        % Value changing function: t3Slider
        function t3SliderValueChanging(app, event)
            changingValue = event.Value;
            if forwardKinTest(app.t1,app.t2,changingValue, app.R, app.ZMin, app.ZMax)
                app.TeleoperateAnglePanel.BackgroundColor = [0.94,0.94,0.94];
                app.t3 = changingValue;
                app.sendGoToAngle(app.t1,app.t2,app.t3);
                app.updateView();
            else
                app.TeleoperateAnglePanel.BackgroundColor = [1,0,0];
            end
        end

        % Selection changed function: StickViewButtonGroup
        function StickViewButtonGroupSelectionChanged(app, event)
            app.updateView();
            selectedButton = app.StickViewButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Enable')
                app.StickUIAxes.Visible = 1;
                app.stickFlag = 0;
                axis(app.StickUIAxes,[-50 50 -50 50 -80 25])
                axis(app.StickUIAxes,'square')
                xlabel(app.StickUIAxes,'x')
                ylabel(app.StickUIAxes,'y')
                zlabel(app.StickUIAxes,'z')
                grid(app.StickUIAxes,'on')
                view(app.StickUIAxes,-25,25)
                app.updateView();
            else
                cla(app.StickUIAxes,'reset')
                app.StickUIAxes.Visible = 0;
            end
        end

        % Selection changed function: TeleoperateAngleButtonGroup
        function TeleoperateAngleButtonGroupSelectionChanged(app, event)
            selectedButton = app.TeleoperateAngleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Enable')
                app.t1Slider.Enable = 1;
                app.t2Slider.Enable = 1;
                app.t3Slider.Enable = 1;
                app.updateView();
                
            else
                app.t1Slider.Enable = 0;
                app.t2Slider.Enable = 0;
                app.t3Slider.Enable = 0;
                app.t1Slider.Value = 0;
                app.t2Slider.Value = 0;
                app.t3Slider.Value = 0;
            end
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            app.ConfigureButton.Enable = 'off';
            ogPort = app.Port;
            
            
            % Open the options dialog and pass inputs
            app.DialogApp = DialogSettingsApp(app, app.R, app.ZMin,app.ZMax,app.Port);
            if strcmp(ogPort,app.Port)==false
                app.serialObj = serialport(app.Port,9600);
                flush(app.serialObj);
                configureCallback(app.serialObj, "terminator", @app.serialObjCallback);
            end
            Pos1ResetButtonPushed(app)
            Pos2ResetButtonPushed(app)
            Pos3ResetButtonPushed(app)
            Pos4ResetButtonPushed(app)
        end

        % Close request function: DeltaZGUIUIFigure
        function DeltaZGUIUIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
            
        end

        % Size changed function: TeleoperatePositionPanel
        function TeleoperatePositionPanelSizeChanged(app, event)
            %             position = app.TeleoperatePositionPanel.Position;
            %             app.UIAxes.Position = [15 25 position(4)*.85 position(4)*.85];
            %             app.ZPositionSlider.Position = [position(1)+position(3)-.13*position(3) position(2)+.05*position(4) 3 position(4)*.75];
        end

        % Size changed function: StickPanel
        function StickPanelSizeChanged(app, event)
            %             position = app.StickPanel.Position;
            %             app.StickUIAxes.Position = [position(1) position(2) position(4)*.9 position(4)*.9];
            %             app.StickViewButtonGroup.Position = [1 1 100 73];
        end

        % Button pushed function: Pos1SetButton
        function Pos1SetButtonPushed(app, event)
            app.savedPos1(1) = app.Point.XData(1);
            app.savedPos1(2) = app.Point.YData(1);
            app.savedPos1(3) = app.ZPos;
            label = sprintf("x = %.2f, y = %.2f, z = %.2f",app.savedPos1(1),app.savedPos1(2),app.savedPos1(3));
            app.Pos1ValsLabel.Text = label;
            
            %             if sqrt(x0*x0+y0*y0) <= app.R && z0>=app.ZMin && z0<=app.ZMax
            %                 app.Pos1XEditField.Value = app.Point.XData(1);
            %                 app.Pos1YEditField.Value = app.Point.YData(1)
            %                 app.Pos1ZEditField.Value = app.ZPos;
            %             else
            %                 app.Pos1XEditField.Value = 0;
            %                 app.Pos1YEditField.Value = 0;
            %                 app.Pos1ZEditField.Value = -47;
            %                 fprintf("hi\n");
            %                 beep;
            %             end
        end

        % Button pushed function: Pos2SetButton
        function Pos2SetButtonPushed(app, event)
            app.savedPos2(1) = app.Point.XData(1);
            app.savedPos2(2) = app.Point.YData(1);
            app.savedPos2(3) = app.ZPos;
            label = sprintf("x = %.2f, y = %.2f, z = %.2f",app.savedPos2(1),app.savedPos2(2),app.savedPos2(3));
            app.Pos2ValsLabel.Text = label;
        end

        % Button pushed function: Pos3SetButton
        function Pos3SetButtonPushed(app, event)
            app.savedPos3(1) = app.Point.XData(1);
            app.savedPos3(2) = app.Point.YData(1);
            app.savedPos3(3) = app.ZPos;
            label = sprintf("x = %.2f, y = %.2f, z = %.2f",app.savedPos3(1),app.savedPos3(2),app.savedPos3(3));
            app.Pos3ValsLabel.Text = label;
        end

        % Button pushed function: Pos4SetButton
        function Pos4SetButtonPushed(app, event)
            app.savedPos4(1) = app.Point.XData(1);
            app.savedPos4(2) = app.Point.YData(1);
            app.savedPos4(3) = app.ZPos;
            label = sprintf("x = %.0f, y = %.0f, z = %.0f",app.savedPos4(1),app.savedPos4(2),app.savedPos4(3));
            app.Pos4ValsLabel.Text = label;
        end

        % Button pushed function: Pos1GoButton
        function Pos1GoButtonPushed(app, event)
            app.Point.XData(1) = app.savedPos1(1);
            app.Point.YData(1) = app.savedPos1(2);
            app.ZPos = app.savedPos1(3);
            app.updateView()
            app.sendGoTo(app.Point.XData(1),app.Point.YData(1),app.ZPos)
        end

        % Button pushed function: Pos2GoButton
        function Pos2GoButtonPushed(app, event)
            app.Point.XData(1) = app.savedPos2(1);
            app.Point.YData(1) = app.savedPos2(2);
            app.ZPos = app.savedPos2(3);
            app.updateView()
            app.sendGoTo(app.Point.XData(1),app.Point.YData(1),app.ZPos)
        end

        % Button pushed function: Pos3GoButton
        function Pos3GoButtonPushed(app, event)
            app.Point.XData(1) = app.savedPos3(1);
            app.Point.YData(1) = app.savedPos3(2);
            app.ZPos = app.savedPos3(3);
            app.updateView()
            app.sendGoTo(app.Point.XData(1),app.Point.YData(1),app.ZPos)
        end

        % Button pushed function: Pos4GoButton
        function Pos4GoButtonPushed(app, event)
            app.Point.XData(1) = app.savedPos4(1);
            app.Point.YData(1) = app.savedPos4(2);
            app.ZPos = app.savedPos4(3);
            app.updateView()
            app.sendGoTo(app.Point.XData(1),app.Point.YData(1),app.ZPos)
        end

        % Button pushed function: Pos1ResetButton
        function Pos1ResetButtonPushed(app, event)
            app.savedPos1(1) = 0;
            app.savedPos1(2) = 0;
            app.savedPos1(3) = app.zHome;
            label = sprintf("x = %.2f, y = %.2f, z = %.2f",app.savedPos1(1),app.savedPos1(2),app.savedPos1(3));
            app.Pos1ValsLabel.Text = label;
        end

        % Button pushed function: Pos2ResetButton
        function Pos2ResetButtonPushed(app, event)
            app.savedPos2(1) = 0;
            app.savedPos2(2) = 0;
            app.savedPos2(3) = app.zHome;
            label = sprintf("x = %.2f, y = %.2f, z = %.2f",app.savedPos2(1),app.savedPos2(2),app.savedPos2(3));
            app.Pos2ValsLabel.Text = label;
        end

        % Button pushed function: Pos3ResetButton
        function Pos3ResetButtonPushed(app, event)
            app.savedPos3(1) = 0;
            app.savedPos3(2) = 0;
            app.savedPos3(3) = app.zHome;
            label = sprintf("x = %.2f, y = %.2f, z = %.2f",app.savedPos3(1),app.savedPos3(2),app.savedPos3(3));
            app.Pos3ValsLabel.Text = label;
        end

        % Button pushed function: Pos4ResetButton
        function Pos4ResetButtonPushed(app, event)
            app.savedPos4(1) = 0;
            app.savedPos4(2) = 0;
            app.savedPos4(3) = app.zHome;
            label = sprintf("x = %.2f, y = %.2f, z = %.2f",app.savedPos4(1),app.savedPos4(2),app.savedPos4(3));
            app.Pos4ValsLabel.Text = label;
        end

        % Button pushed function: Pos1ResetButton_2
        function Pos1ResetButton_2Pushed(app, event)
            Pos1GoButtonPushed(app)
            pause(.5);
            Pos2GoButtonPushed(app)
            pause(.5);
            Pos3GoButtonPushed(app)
            pause(.5);
            Pos4GoButtonPushed(app)
        end

        % Callback function
        function PortEditFieldValueChanged(app, event)
            %             value = app.PortEditField.Value;
            %             app.Port = value;
        end

        % Callback function
        function PortEditFieldValueChanged2(app, event)
            value = app.PortEditField.Value;
            app.Port = value;
        end

        % Button pushed function: MoveToGoButton
        function MoveToGoButtonPushed(app, event)
            x0 = app.XEditFieldMoveTo.Value;
            y0 = app.YEditFieldMoveTo.Value;
            z0 = app.ZEditFieldMoveTo.Value;
            
            if sqrt(x0*x0+y0*y0) <= app.R && z0>=app.ZMin && z0<=app.ZMax
                app.Point.XData(1) = x0;
                app.Point.YData(1) = y0;
                app.ZPos = z0;
                app.updateView()
                app.sendGoTo(app.Point.XData(1),app.Point.YData(1),app.ZPos)
            else
                beep
                app.XEditFieldMoveTo.Value=0;
                app.YEditFieldMoveTo.Value=0;
                app.ZEditFieldMoveTo.Value=app.zHome;
            end
            
        end

        % Button pushed function: AngleMoveToGoButton
        function AngleMoveToGoButtonPushed(app, event)
            ang1 = app.t1EditFieldMoveTo.Value;
            ang2 = app.t2EditFieldMoveTo.Value;
            ang3 = app.t3EditFieldMoveTo.Value;
            
            if forwardKinTest(ang1,ang2,ang3, app.R, app.ZMin, app.ZMax)
                app.t1 = ang1;
                app.t2 = ang2;
                app.t3 = ang3;
                app.updateView()
                app.sendGoToAngle(ang1,ang2,ang3)
            else
                beep
                app.t1EditFieldMoveTo.Value=0;
                app.t1EditFieldMoveTo.Value=0;
                app.t1EditFieldMoveTo.Value=0;
            end
        end

        % Button pushed function: SF1Button
        function SF1ButtonPushed(app, event)
            app.sendSF(1);
        end

        % Button pushed function: SF2Button
        function SF2ButtonPushed(app, event)
            app.sendSF(2);
        end

        % Button pushed function: SF3Button
        function SF3ButtonPushed(app, event)
            app.sendSF(3);
        end

        % Button pushed function: SF4Button
        function SF4ButtonPushed(app, event)
            app.sendSF(4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeltaZGUIUIFigure = uifigure('Visible', 'off');
            app.DeltaZGUIUIFigure.Name = 'DeltaZ GUI';
            app.DeltaZGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @DeltaZGUIUIFigureCloseRequest, true);
            app.DeltaZGUIUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @DeltaZGUIUIFigureWindowButtonUp, true);
            app.DeltaZGUIUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @DeltaZGUIUIFigureWindowButtonDown, true);
            app.Label = uilabel(app.DeltaZGUIUIFigure);
            app.Label.Visible = 'off';
            app.HomeButton = uibutton(app.DeltaZGUIUIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.DeltaZLabel = uilabel(app.DeltaZGUIUIFigure);
            app.DeltaZLabel.Text = 'DeltaZ';
            app.CurrentPositionPanel = uipanel(app.DeltaZGUIUIFigure);
            app.CurrentPositionPanel.Title = 'Current Position';
            app.XEditFieldLabel = uilabel(app.CurrentPositionPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CurrentPositionPanel, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.2f';
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.CurrentPositionPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CurrentPositionPanel, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.2f';
            app.YEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.CurrentPositionPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.CurrentPositionPanel, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.2f';
            app.ZEditField.Editable = 'off';
            app.ZEditField.Value = -47.06;
            app.TeleoperatePositionPanel = uipanel(app.DeltaZGUIUIFigure);
            app.TeleoperatePositionPanel.Title = 'Teleoperate - Position';
            app.TeleoperatePositionPanel.SizeChangedFcn = createCallbackFcn(app, @TeleoperatePositionPanelSizeChanged, true);
            app.UIAxes = uiaxes(app.TeleoperatePositionPanel);
            title(app.UIAxes, 'X-Y Position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ZPositionSlider = uislider(app.TeleoperatePositionPanel);
            app.ZPositionSlider.Limits = [-75 -35];
            app.ZPositionSlider.ValueChangingFcn = createCallbackFcn(app, @ZPositionSliderValueChanging, true);
            app.ZPositionSlider.Value = -47.06;
            app.ZPositionLabel = uilabel(app.TeleoperatePositionPanel);
            app.ZPositionLabel.Text = 'Z Position';
            app.MoveToPositionPanel = uipanel(app.DeltaZGUIUIFigure);
            app.MoveToPositionPanel.Title = 'Move To Position';
            app.XEditField_2Label = uilabel(app.MoveToPositionPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditFieldMoveTo = uieditfield(app.MoveToPositionPanel, 'numeric');
            app.XEditFieldMoveTo.ValueDisplayFormat = '%.2f';
            app.YEditField_2Label = uilabel(app.MoveToPositionPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditFieldMoveTo = uieditfield(app.MoveToPositionPanel, 'numeric');
            app.YEditFieldMoveTo.ValueDisplayFormat = '%.2f';
            app.ZEditField_2Label = uilabel(app.MoveToPositionPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditFieldMoveTo = uieditfield(app.MoveToPositionPanel, 'numeric');
            app.ZEditFieldMoveTo.ValueDisplayFormat = '%.2f';
            app.ZEditFieldMoveTo.Value = -47.06;
            app.MoveToGoButton = uibutton(app.MoveToPositionPanel, 'push');
            app.MoveToGoButton.ButtonPushedFcn = createCallbackFcn(app, @MoveToGoButtonPushed, true);
            app.MoveToGoButton.Text = 'XYZ Go';
            app.EditField_4Label = uilabel(app.MoveToPositionPanel);
            app.EditField_4Label.Text = 'θ1';
            app.t1EditFieldMoveTo = uieditfield(app.MoveToPositionPanel, 'numeric');
            app.EditField_5Label = uilabel(app.MoveToPositionPanel);
            app.EditField_5Label.Text = 'θ2';
            app.t2EditFieldMoveTo = uieditfield(app.MoveToPositionPanel, 'numeric');
            app.EditField_6Label = uilabel(app.MoveToPositionPanel);
            app.EditField_6Label.Text = 'θ3';
            app.t3EditFieldMoveTo = uieditfield(app.MoveToPositionPanel, 'numeric');
            app.AngleMoveToGoButton = uibutton(app.MoveToPositionPanel, 'push');
            app.AngleMoveToGoButton.ButtonPushedFcn = createCallbackFcn(app, @AngleMoveToGoButtonPushed, true);
            app.AngleMoveToGoButton.Text = 'θ Go';
            app.CurrentJointAnglesPanel = uipanel(app.DeltaZGUIUIFigure);
            app.CurrentJointAnglesPanel.Title = 'Current Joint Angles';
            app.EditFieldLabel = uilabel(app.CurrentJointAnglesPanel);
            app.EditFieldLabel.Text = 'θ1';
            app.t1EditField = uieditfield(app.CurrentJointAnglesPanel, 'numeric');
            app.t1EditField.Editable = 'off';
            app.EditField_2Label = uilabel(app.CurrentJointAnglesPanel);
            app.EditField_2Label.Text = 'θ2';
            app.t2EditField = uieditfield(app.CurrentJointAnglesPanel, 'numeric');
            app.t2EditField.Editable = 'off';
            app.EditField_3Label = uilabel(app.CurrentJointAnglesPanel);
            app.EditField_3Label.Text = 'θ3';
            app.t3EditField = uieditfield(app.CurrentJointAnglesPanel, 'numeric');
            app.t3EditField.Editable = 'off';
            app.SpecialFunctionsPanel = uipanel(app.DeltaZGUIUIFigure);
            app.SpecialFunctionsPanel.Title = 'Special Functions';
            app.SF2Button = uibutton(app.SpecialFunctionsPanel, 'push');
            app.SF2Button.ButtonPushedFcn = createCallbackFcn(app, @SF2ButtonPushed, true);
            app.SF2Button.Text = 'SF2';
            app.SF1Button = uibutton(app.SpecialFunctionsPanel, 'push');
            app.SF1Button.ButtonPushedFcn = createCallbackFcn(app, @SF1ButtonPushed, true);
            app.SF1Button.Text = 'SF1';
            app.SF3Button = uibutton(app.SpecialFunctionsPanel, 'push');
            app.SF3Button.ButtonPushedFcn = createCallbackFcn(app, @SF3ButtonPushed, true);
            app.SF3Button.Text = 'SF3';
            app.SF4Button = uibutton(app.SpecialFunctionsPanel, 'push');
            app.SF4Button.ButtonPushedFcn = createCallbackFcn(app, @SF4ButtonPushed, true);
            app.SF4Button.Text = 'SF4';
            app.TeleoperateAnglePanel = uipanel(app.DeltaZGUIUIFigure);
            app.TeleoperateAnglePanel.Title = 'Teleoperate - Angle';
            app.SliderLabel = uilabel(app.TeleoperateAnglePanel);
            app.SliderLabel.Enable = 'off';
            app.SliderLabel.Text = 'θ1';
            app.t1Slider = uislider(app.TeleoperateAnglePanel);
            app.t1Slider.Limits = [-90 90];
            app.t1Slider.ValueChangingFcn = createCallbackFcn(app, @t1SliderValueChanging, true);
            app.t1Slider.Enable = 'off';
            app.Label_5 = uilabel(app.TeleoperateAnglePanel);
            app.Label_5.Enable = 'off';
            app.Label_5.Text = 'θ2';
            app.t2Slider = uislider(app.TeleoperateAnglePanel);
            app.t2Slider.Limits = [-90 90];
            app.t2Slider.ValueChangingFcn = createCallbackFcn(app, @t2SliderValueChanging, true);
            app.t2Slider.Enable = 'off';
            app.Label_6 = uilabel(app.TeleoperateAnglePanel);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = 'θ3';
            app.t3Slider = uislider(app.TeleoperateAnglePanel);
            app.t3Slider.Limits = [-90 90];
            app.t3Slider.ValueChangingFcn = createCallbackFcn(app, @t3SliderValueChanging, true);
            app.t3Slider.Enable = 'off';
            app.TeleoperateAngleButtonGroup = uibuttongroup(app.TeleoperateAnglePanel);
            app.TeleoperateAngleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TeleoperateAngleButtonGroupSelectionChanged, true);
            app.TeleoperateAngleButtonGroup.Title = 'Teleoperate - Angle';
            app.EnableButton_2 = uiradiobutton(app.TeleoperateAngleButtonGroup);
            app.EnableButton_2.Text = 'Enable';
            app.DisableButton_2 = uiradiobutton(app.TeleoperateAngleButtonGroup);
            app.DisableButton_2.Text = 'Disable';
            app.DisableButton_2.Value = true;
            app.SavePositionPanel = uipanel(app.DeltaZGUIUIFigure);
            app.SavePositionPanel.Title = 'Save Position';
            app.Pos1GoButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos1GoButton.ButtonPushedFcn = createCallbackFcn(app, @Pos1GoButtonPushed, true);
            app.Pos1GoButton.Text = 'Go';
            app.Pos1SetButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos1SetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos1SetButtonPushed, true);
            app.Pos1SetButton.Text = 'Set';
            app.Pos2GoButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos2GoButton.ButtonPushedFcn = createCallbackFcn(app, @Pos2GoButtonPushed, true);
            app.Pos2GoButton.Text = 'Go';
            app.Pos2SetButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos2SetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos2SetButtonPushed, true);
            app.Pos2SetButton.Text = 'Set';
            app.Pos3GoButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos3GoButton.ButtonPushedFcn = createCallbackFcn(app, @Pos3GoButtonPushed, true);
            app.Pos3GoButton.Text = 'Go';
            app.Pos3SetButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos3SetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos3SetButtonPushed, true);
            app.Pos3SetButton.Text = 'Set';
            app.Pos1Label = uilabel(app.SavePositionPanel);
            app.Pos1Label.Text = '1)';
            app.Pos2Label = uilabel(app.SavePositionPanel);
            app.Pos2Label.Text = '2)';
            app.Pos3Label = uilabel(app.SavePositionPanel);
            app.Pos3Label.Text = '3)';
            app.Pos1ResetButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos1ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos1ResetButtonPushed, true);
            app.Pos1ResetButton.Text = 'Reset';
            app.Pos2ResetButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos2ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos2ResetButtonPushed, true);
            app.Pos2ResetButton.Text = 'Reset';
            app.Pos3ResetButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos3ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos3ResetButtonPushed, true);
            app.Pos3ResetButton.Text = 'Reset';
            app.Pos4GoButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos4GoButton.ButtonPushedFcn = createCallbackFcn(app, @Pos4GoButtonPushed, true);
            app.Pos4GoButton.Text = 'Go';
            app.Pos4SetButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos4SetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos4SetButtonPushed, true);
            app.Pos4SetButton.Text = 'Set';
            app.Pos4Label = uilabel(app.SavePositionPanel);
            app.Pos4Label.Text = '4)';
            app.Pos4ResetButton = uibutton(app.SavePositionPanel, 'push');
            app.Pos4ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Pos4ResetButtonPushed, true);
            app.Pos4ResetButton.Text = 'Reset';
            app.Pos1ValsLabel = uilabel(app.SavePositionPanel);
            app.Pos1ValsLabel.Text = 'x = 0.00, y = 0.00, z = -47.06';
            app.Pos2ValsLabel = uilabel(app.SavePositionPanel);
            app.Pos2ValsLabel.Text = 'x = 0.00, y = 0.00, z = -47.06';
            app.Pos4ValsLabel = uilabel(app.SavePositionPanel);
            app.Pos4ValsLabel.Text = 'x = 0.00, y = 0.00, z = -47.06';
            app.Pos3ValsLabel = uilabel(app.SavePositionPanel);
            app.Pos3ValsLabel.Text = 'x = 0.00, y = 0.00, z = -47.06';
            app.Pos1ResetButton_2 = uibutton(app.SavePositionPanel, 'push');
            app.Pos1ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @Pos1ResetButton_2Pushed, true);
            app.Pos1ResetButton_2.Text = {'Run  '; 'Sequence'};
            app.StickPanel = uipanel(app.DeltaZGUIUIFigure);
            app.StickPanel.Title = '3D Model';
            app.StickPanel.SizeChangedFcn = createCallbackFcn(app, @StickPanelSizeChanged, true);
            app.StickUIAxes = uiaxes(app.StickPanel);
            title(app.StickUIAxes, 'Title')
            xlabel(app.StickUIAxes, 'X')
            ylabel(app.StickUIAxes, 'Y')
            app.StickUIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.StickUIAxes.Visible = 'off';
            app.StickViewButtonGroup = uibuttongroup(app.StickPanel);
            app.StickViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StickViewButtonGroupSelectionChanged, true);
            app.StickViewButtonGroup.Title = 'Stick View';
            app.EnableButton = uiradiobutton(app.StickViewButtonGroup);
            app.EnableButton.Text = 'Enable';
            app.DisableButton = uiradiobutton(app.StickViewButtonGroup);
            app.DisableButton.Text = 'Disable';
            app.DisableButton.Value = true;
            app.ConfigureButton = uibutton(app.DeltaZGUIUIFigure, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = {'Configure '; ''};
            % Show the figure after all components are created
            app.DeltaZGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeltaZ_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeltaZGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeltaZGUIUIFigure)
        end
    end
end