classdef DialogSettingsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button                matlab.ui.control.Button
        RadiusEditFieldLabel  matlab.ui.control.Label
        RadiusEditField       matlab.ui.control.NumericEditField
        ZMinLabel             matlab.ui.control.Label
        ZMinEditField         matlab.ui.control.NumericEditField
        ZmaxLabel             matlab.ui.control.Label
        ZMaxEditField         matlab.ui.control.NumericEditField
        RevertDefaultButton   matlab.ui.control.Button
        PortEditFieldLabel    matlab.ui.control.Label
        PortEditField         matlab.ui.control.EditField
    end


    properties (Access = private) 
        CallingApp   % Main app object 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, R, ZMin, ZMax, Port)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.RadiusEditField.Value = R;
            app.ZMinEditField.Value = ZMin;
            app.ZMaxEditField.Value = ZMax;
            app.PortEditField.Value = Port;
%             app.DropDown.Value = c;
%             app.DropDown.Value = c;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.CallingApp.R = app.RadiusEditField.Value;
            app.CallingApp.ZMin = app.ZMinEditField.Value;
            app.CallingApp.ZMax = app.ZMaxEditField.Value;
            app.CallingApp.Port = app.PortEditField.Value;
            app.CallingApp.ZPositionSlider.Limits = [app.CallingApp.ZMin app.CallingApp.ZMax];
            app.CallingApp.updateXYPlot();
            app.CallingApp.goHome();
            app.CallingApp.ConfigureButton.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.ConfigureButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: RevertDefaultButton
        function RevertDefaultButtonPushed(app, event)
            app.CallingApp.R = 30;
            app.CallingApp.ZMin = -75;
            app.CallingApp.ZMax = -35;
            app.CallingApp.ZPositionSlider.Limits = [app.CallingApp.ZMin app.CallingApp.ZMax];
            app.CallingApp.updateXYPlot();
            app.CallingApp.goHome();
            app.CallingApp.ConfigureButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Configure Parameters';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'OK';
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius ';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusEditField.Limits = [1 30];
            app.RadiusEditField.Value = 30;
            app.ZMinLabel = uilabel(app.UIFigure);
            app.ZMinLabel.Text = 'ZMin';
            app.ZMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZMinEditField.Limits = [-75 -35];
            app.ZMinEditField.Value = -35;
            app.ZmaxLabel = uilabel(app.UIFigure);
            app.ZmaxLabel.Text = 'Zmax';
            app.ZMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZMaxEditField.Limits = [-75 -35];
            app.ZMaxEditField.Value = -75;
            app.RevertDefaultButton = uibutton(app.UIFigure, 'push');
            app.RevertDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RevertDefaultButtonPushed, true);
            app.RevertDefaultButton.Text = 'Revert to Defaults';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            app.PortEditField.Editable = 'off';
            app.PortEditField.Value = 'COM5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogSettingsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end